classdef RangeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SavevariablerangesandrunmastermButton  matlab.ui.control.Button
        PlaneaspectratioSlider         matlab.ui.control.RangeSlider
        PlaneaspectratioSliderLabel    matlab.ui.control.Label
        spanofthestabilisersSlider     matlab.ui.control.RangeSlider
        spanofthestabilisersSliderLabel  matlab.ui.control.Label
        RootchordofthestabilisersSlider  matlab.ui.control.RangeSlider
        RootchordofthestabilisersSliderLabel  matlab.ui.control.Label
        PositionofthewingSlider        matlab.ui.control.RangeSlider
        PositionofthewingSliderLabel   matlab.ui.control.Label
        WingspanSlider                 matlab.ui.control.RangeSlider
        WingspanSliderLabel            matlab.ui.control.Label
        FuselagewidthSlider            matlab.ui.control.RangeSlider
        FuselagewidthSliderLabel       matlab.ui.control.Label
        RootchordofthewingSlider       matlab.ui.control.RangeSlider
        RootchordofthewingSliderLabel  matlab.ui.control.Label
        TaperratioofthewingSlider      matlab.ui.control.RangeSlider
        Label                          matlab.ui.control.Label
        MassofthemedicalsupplycabinetSlider  matlab.ui.control.RangeSlider
        MassofthemedicalsupplycabinetSliderLabel  matlab.ui.control.Label
        TotalnumberofpassengersSlider  matlab.ui.control.RangeSlider
        TotalnumberofpassengersSliderLabel  matlab.ui.control.Label
        NumberofpassengersinthewidthdirectionSlider  matlab.ui.control.RangeSlider
        NumberofpassengersinthewidthdirectionSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ranges = zeros(11,2);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % NumberofpassengersinthewidthdirectionSlider
        function NumberofpassengersinthewidthdirectionSliderValueChanged(app, event)
            app.ranges(1,:) = app.NumberofpassengersinthewidthdirectionSlider.Value;
            
        end

        % Value changed function: TotalnumberofpassengersSlider
        function TotalnumberofpassengersSliderValueChanged(app, event)
            app.ranges(2,:) = app.TotalnumberofpassengersSlider.Value;
            
        end

        % Value changed function: MassofthemedicalsupplycabinetSlider
        function MassofthemedicalsupplycabinetSliderValueChanged(app, event)
            app.ranges(3,:) = app.MassofthemedicalsupplycabinetSlider.Value;
            
        end

        % Value changed function: TaperratioofthewingSlider
        function TaperratioofthewingSliderValueChanged(app, event)
            app.ranges(4,:) = app.TaperratioofthewingSlider.Value;
            
        end

        % Value changed function: RootchordofthewingSlider
        function RootchordofthewingSliderValueChanged(app, event)
            app.ranges(5,:)  = app.RootchordofthewingSlider.Value;
            
        end

        % Value changed function: FuselagewidthSlider
        function FuselagewidthSliderValueChanged(app, event)
            app.ranges(6,:) = app.FuselagewidthSlider.Value;
            
        end

        % Value changed function: WingspanSlider
        function WingspanSliderValueChanged(app, event)
            app.ranges(7,:) = app.WingspanSlider.Value;
            
        end

        % Value changed function: PositionofthewingSlider
        function PositionofthewingSliderValueChanged(app, event)
            app.ranges(8,:) = app.PositionofthewingSlider.Value;
            
        end

        % Value changed function: RootchordofthestabilisersSlider
        function RootchordofthestabilisersSliderValueChanged(app, event)
            app.ranges(9,:) = app.RootchordofthestabilisersSlider.Value;
            
        end

        % Value changed function: spanofthestabilisersSlider
        function spanofthestabilisersSliderValueChanged(app, event)
            app.ranges(10,:) = app.spanofthestabilisersSlider.Value;
            
        end

        % Value changed function: PlaneaspectratioSlider
        function PlaneaspectratioSliderValueChanged(app, event)
            app.ranges(11,:) = app.PlaneaspectratioSlider.Value;
            
        end

        % Button pushed function: SavevariablerangesandrunmastermButton
        function SavevariablerangesandrunmastermButtonPushed(app, event)
            NumberofpassengersinthewidthdirectionSliderValueChanged(app, event);
            TotalnumberofpassengersSliderValueChanged(app, event);
            MassofthemedicalsupplycabinetSliderValueChanged(app, event);
            TaperratioofthewingSliderValueChanged(app, event);
            RootchordofthewingSliderValueChanged(app, event);
            FuselagewidthSliderValueChanged(app, event);
            WingspanSliderValueChanged(app, event);
            PositionofthewingSliderValueChanged(app, event);
            RootchordofthestabilisersSliderValueChanged(app, event);
            spanofthestabilisersSliderValueChanged(app, event);
            PlaneaspectratioSliderValueChanged(app, event);

            ranges = app.ranges;
            save 'ranges.mat' ranges;


            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofpassengersinthewidthdirectionSliderLabel = uilabel(app.UIFigure);
            app.NumberofpassengersinthewidthdirectionSliderLabel.Text = 'Number of passengers in the width direction';
            app.NumberofpassengersinthewidthdirectionSlider = uislider(app.UIFigure, 'range');
            app.NumberofpassengersinthewidthdirectionSlider.Limits = [1 8];
            app.NumberofpassengersinthewidthdirectionSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.NumberofpassengersinthewidthdirectionSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofpassengersinthewidthdirectionSliderValueChanged, true);
            app.NumberofpassengersinthewidthdirectionSlider.Value = [1 2];
            app.TotalnumberofpassengersSliderLabel = uilabel(app.UIFigure);
            app.TotalnumberofpassengersSliderLabel.Text = 'Total number of passengers';
            app.TotalnumberofpassengersSlider = uislider(app.UIFigure, 'range');
            app.TotalnumberofpassengersSlider.Limits = [1 20];
            app.TotalnumberofpassengersSlider.MajorTicks = [1 10 20 30 40 50];
            app.TotalnumberofpassengersSlider.ValueChangedFcn = createCallbackFcn(app, @TotalnumberofpassengersSliderValueChanged, true);
            app.TotalnumberofpassengersSlider.Value = [1 4];
            app.MassofthemedicalsupplycabinetSliderLabel = uilabel(app.UIFigure);
            app.MassofthemedicalsupplycabinetSliderLabel.Text = 'Mass of the medical supply cabinet';
            app.MassofthemedicalsupplycabinetSlider = uislider(app.UIFigure, 'range');
            app.MassofthemedicalsupplycabinetSlider.Limits = [0 1];
            app.MassofthemedicalsupplycabinetSlider.ValueChangedFcn = createCallbackFcn(app, @MassofthemedicalsupplycabinetSliderValueChanged, true);
            app.MassofthemedicalsupplycabinetSlider.Value = [0 0.15];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Taper ratio of the wing';
            app.TaperratioofthewingSlider = uislider(app.UIFigure, 'range');
            app.TaperratioofthewingSlider.Limits = [0.1 1];
            app.TaperratioofthewingSlider.ValueChangedFcn = createCallbackFcn(app, @TaperratioofthewingSliderValueChanged, true);
            app.TaperratioofthewingSlider.Value = [0.1 0.5];
            app.RootchordofthewingSliderLabel = uilabel(app.UIFigure);
            app.RootchordofthewingSliderLabel.Text = 'Root chord of the wing';
            app.RootchordofthewingSlider = uislider(app.UIFigure, 'range');
            app.RootchordofthewingSlider.Limits = [0.1 1];
            app.RootchordofthewingSlider.ValueChangedFcn = createCallbackFcn(app, @RootchordofthewingSliderValueChanged, true);
            app.RootchordofthewingSlider.Value = [0.2 0.4];
            app.FuselagewidthSliderLabel = uilabel(app.UIFigure);
            app.FuselagewidthSliderLabel.Text = 'Fuselage width';
            app.FuselagewidthSlider = uislider(app.UIFigure, 'range');
            app.FuselagewidthSlider.Limits = [0.1 0.5];
            app.FuselagewidthSlider.ValueChangedFcn = createCallbackFcn(app, @FuselagewidthSliderValueChanged, true);
            app.FuselagewidthSlider.Value = [0.1 0.5];
            app.WingspanSliderLabel = uilabel(app.UIFigure);
            app.WingspanSliderLabel.Text = 'Wing span';
            app.WingspanSlider = uislider(app.UIFigure, 'range');
            app.WingspanSlider.Limits = [0 1.5];
            app.WingspanSlider.ValueChangedFcn = createCallbackFcn(app, @WingspanSliderValueChanged, true);
            app.WingspanSlider.Value = [0.8 1.5];
            app.PositionofthewingSliderLabel = uilabel(app.UIFigure);
            app.PositionofthewingSliderLabel.Text = 'Position of the wing';
            app.PositionofthewingSlider = uislider(app.UIFigure, 'range');
            app.PositionofthewingSlider.Limits = [0 0.4];
            app.PositionofthewingSlider.ValueChangedFcn = createCallbackFcn(app, @PositionofthewingSliderValueChanged, true);
            app.PositionofthewingSlider.Value = [0.2 0.3];
            app.RootchordofthestabilisersSliderLabel = uilabel(app.UIFigure);
            app.RootchordofthestabilisersSliderLabel.Text = 'Root chord of the stabilisers';
            app.RootchordofthestabilisersSlider = uislider(app.UIFigure, 'range');
            app.RootchordofthestabilisersSlider.Limits = [0.05 0.3];
            app.RootchordofthestabilisersSlider.MajorTicks = [0.05 0.1 0.15 0.2 0.25 0.3];
            app.RootchordofthestabilisersSlider.ValueChangedFcn = createCallbackFcn(app, @RootchordofthestabilisersSliderValueChanged, true);
            app.RootchordofthestabilisersSlider.Value = [0.06 0.2];
            app.spanofthestabilisersSliderLabel = uilabel(app.UIFigure);
            app.spanofthestabilisersSliderLabel.Text = 'span of the stabilisers';
            app.spanofthestabilisersSlider = uislider(app.UIFigure, 'range');
            app.spanofthestabilisersSlider.Limits = [0.2 1.4];
            app.spanofthestabilisersSlider.ValueChangedFcn = createCallbackFcn(app, @spanofthestabilisersSliderValueChanged, true);
            app.spanofthestabilisersSlider.Value = [0.2 1.2];
            app.PlaneaspectratioSliderLabel = uilabel(app.UIFigure);
            app.PlaneaspectratioSliderLabel.Text = 'Plane aspect ratio';
            app.PlaneaspectratioSlider = uislider(app.UIFigure, 'range');
            app.PlaneaspectratioSlider.Limits = [0.5 2];
            app.PlaneaspectratioSlider.ValueChangedFcn = createCallbackFcn(app, @PlaneaspectratioSliderValueChanged, true);
            app.PlaneaspectratioSlider.Value = [0.6 1.3];
            app.SavevariablerangesandrunmastermButton = uibutton(app.UIFigure, 'push');
            app.SavevariablerangesandrunmastermButton.ButtonPushedFcn = createCallbackFcn(app, @SavevariablerangesandrunmastermButtonPushed, true);
            app.SavevariablerangesandrunmastermButton.Text = 'Save variable ranges and run master.m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RangeSelector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end